classdef CompareRNG < matlab.apps.AppBase
    %COMPARERNG Compare random number generators
    %   Simple app to benchmark MATLAB's Random Number Generators on your
    %   machine.
    %   
    %   Use this application to view side-by-side comparisons of MATLAB's
    %   Random Number Generator (RNG) performance. Results can optionally
    %   include the GPU-enabled RNGs if you have an NVIDIA GPU and Parallel
    %   Computing Toolbox.
    %   
    %   Copyright 2025 The MathWorks, Inc.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FunctionDropDown         matlab.ui.control.DropDown
        FunctionDropDownLabel    matlab.ui.control.Label
        ExporttoWorkspaceButton  matlab.ui.control.Button
        GPUGeneratorTree         matlab.ui.container.CheckBoxTree
        GPUGeneratorsNode        matlab.ui.container.TreeNode
        CombRecursiveNode_2      matlab.ui.container.TreeNode
        PhiloxNode_2             matlab.ui.container.TreeNode
        ThreefryNode_2           matlab.ui.container.TreeNode
        CPUGeneratorTree         matlab.ui.container.CheckBoxTree
        CPUGeneratorsNode        matlab.ui.container.TreeNode
        TwisterNode              matlab.ui.container.TreeNode
        SIMDTwisterNode          matlab.ui.container.TreeNode
        CombRecursiveNode        matlab.ui.container.TreeNode
        MultFibonacciNode        matlab.ui.container.TreeNode
        PhiloxNode               matlab.ui.container.TreeNode
        ThreefryNode             matlab.ui.container.TreeNode
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        PlottedNames = string.empty(0,1) % List of which generators are currently plotted
        PlottedValues = zeros(0,1) % Performance values for the currently plotted generators
        CanUseGPU = true % Can we use a GPU?
        RandFunction = "rand";
    end
    
    properties (Constant, Access = private)
        GPUSuffix = " (GPU)" % Suffix to use on labels for GPU results
        MaxNameLength = 40  % Length after which to truncate the CPU name
    end
    
    methods (Access = private)
        
        function plotResults(app)
            % Update the plot based on the current selection
            maxTime = 2;

            disable(app);
            cleaner = onCleanup(@() enable(app));

            selectedNames = getSelectedGenerators(app);
            isGPUResult = endsWith(selectedNames, app.GPUSuffix);
            if app.RandFunction == "randn"
                randFcn = @randn;
            else
                randFcn = @rand;
            end
            
            % If using a GPU we need to wait for calculations to finish.
            if isGPUResult
                gpu = gpuDevice();
                waitFcn = @() wait(gpu);
            else
                waitFcn = @() [];
            end

            % Work out which existing results to keep
            newResults = nan(size(selectedNames));
            [~,ia,ib] = intersect(selectedNames,app.PlottedNames,"stable");
            newResults(ia) = app.PlottedValues(ib);

            % Replace the plotted variables
            app.PlottedNames = selectedNames;
            app.PlottedValues = newResults;
            hold(app.UIAxes,"off")
            cpuBars = bar(app.UIAxes, find(~isGPUResult), newResults(~isGPUResult), "b");
            hold(app.UIAxes,"on")
            gpuBars = bar(app.UIAxes, find(isGPUResult), newResults(isGPUResult), "g");
            app.UIAxes.XLim = [0.4 numel(newResults)+0.6];
            app.UIAxes.XTick = 1:numel(newResults);
            app.UIAxes.XTickLabels = app.PlottedNames;
            grid(app.UIAxes, "on")
            drawnow

            % Work out which new results we need to compute.
            missingResults = find(isnan(newResults));

            for ii=1:numel(missingResults)
                thisResult = missingResults(ii);
                rs = app.getRandStreamForName(selectedNames(thisResult));
                elapsedTime = 0;
                lastPlotTime = 0;
                newResults(thisResult) = 0;
                N = 1e7;
                % Do a quick warm-up to make sure any startup time is
                % excluded.
                [~] = randFcn(rs,N,1);
                waitFcn();
                % Keep sampling until we reach the time limit, growing the
                % bar to give a nice visual effect.
                while elapsedTime<maxTime
                    tic;
                    [~] = randFcn(rs,N,1);
                    waitFcn();
                    elapsedTime = elapsedTime + toc;
                    newResults(thisResult) = newResults(thisResult) + N/maxTime;

                    % Only plot if a bit of time has passed
                    if (elapsedTime-lastPlotTime)>0.1
                        lastPlotTime = elapsedTime;
                        if isGPUResult(thisResult)
                            gpuBars.YData = newResults(isGPUResult);
                        else
                            cpuBars.YData = newResults(~isGPUResult);
                        end
                        drawnow
                    end
                end
                % Small correction if the time went over max
                newResults(thisResult) = newResults(thisResult)*maxTime/elapsedTime;
                % Do a final plot update just incase
                if isGPUResult(thisResult)
                    gpuBars.YData = newResults(isGPUResult);
                else
                    cpuBars.YData = newResults(~isGPUResult);
                end
                drawnow
            end
            app.PlottedValues = newResults;
            % Add a title that describes the hardware we ran on
            titleStr = "Using "+app.cpuname();
            if any(isGPUResult)
                titleStr = titleStr + newline + "and " + app.gpuname();
            end
            title(app.UIAxes, titleStr);
        end
        
        function rngNames = getSelectedGenerators(app)
            % Scrape the generator selection trees to see what is selected.
            cpuNames = app.getNamesFromNodes(app.CPUGeneratorTree.CheckedNodes);
            gpuNames = app.getNamesFromNodes(app.GPUGeneratorTree.CheckedNodes);
            gpuNames = strcat(gpuNames, app.GPUSuffix);
            % Create a combined CPU+GPU list.
            rngNames = [cpuNames, gpuNames];
        end

        function names = getNamesFromNodes(app, nodes) %#ok<INUSD>
            % Helper to get the name of a UI tree node
            nodes = nodes(arrayfun(@(x) isempty(x.Children), nodes));
            if isempty(nodes)
                names = string.empty(0,1);
            else
                names = string({nodes.Text});
                names = erase(names, " (default)");
            end
        end
        
        function disable(app)
            % Disable all input controls
            app.FunctionDropDown.Enable = "off";
            app.CPUGeneratorTree.Enable = "off";
            app.GPUGeneratorTree.Enable = "off";
            app.ExporttoWorkspaceButton.Enable = "off";
        end

        function enable(app)
            % Enable input controls as appropriate.
            app.FunctionDropDown.Enable = "on";
            app.CPUGeneratorTree.Enable = "on";
            if app.CanUseGPU
                app.GPUGeneratorTree.Enable = "on";
            end
            % Export button is only enabled if there's something to export.
            if ~isempty(app.PlottedValues)
                app.ExporttoWorkspaceButton.Enable = "on";
            end
        end
        
        function rs = getRandStreamForName(app, name)
            % Interpret a name as either a CPU or GPU RandStream.
            if endsWith(name, app.GPUSuffix)
                name = erase(name, app.GPUSuffix);
                rs = parallel.gpu.RandStream(name);
            else
                rs = RandStream(name);
            end
        end
        
        function name = cpuname(app)
            % Try to determine the name of the CPU we are running on.
            if ismac
                [~, infostr] = system( sprintf( "sysctl -a %s", "machdep.cpu" ) );
                name = strtrim(extractAfter(string(infostr), "machdep.cpu.brand_string: "));
            else
                name = string(feature("getcpu"));
            end
            % Make sure one line only and maybe truncate if too long.
            name = splitlines(name);
            if numel(name)>1
                name = name(1);
            end
            if strlength(name)>app.MaxNameLength
                name = eraseBetween(name, app.MaxNameLength, strlength(name));
                name = name + "...";
            end
        end

        function name = gpuname(app)
            % Try to determine the name of the GPU we are running on.
            if app.CanUseGPU
                gpu = gpuDevice();
                name = string(gpu.Name);
            else
                name = "N/A";
            end
            if strlength(name)>app.MaxNameLength
                name = eraseBetween(name, app.MaxNameLength, strlength(name));
                name = name + "...";
            end
        end

        function invalidateResults(app)
            app.PlottedValues = zeros(0,1);
            app.PlottedNames = string.empty(0,1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Change a few things at startup based on the user's
            % environment.

            % Give the window a name!
            app.UIFigure.Name = "Compare MATLAB RNGs";

            % Unfold the trees
            app.CPUGeneratorTree.Children(1).expand();
            app.GPUGeneratorTree.Children(1).expand();

            % Check if we are allowed to use GPU. If not, disable the GPU
            % generator tree.
            app.CanUseGPU = canUseGPU();
            if ~app.CanUseGPU
                app.GPUGeneratorTree.Enable = "off";
            end
            % Append (default) to the default CPU and GPU generators
            default = settings().matlab.general.randomnumbers.DefaultAlgorithm.ActiveValue;
            nodes = app.CPUGeneratorTree.Children.Children;
            idx = find(strcmpi({nodes.Text}, default));
            if isscalar(idx)
                nodes(idx).Text = nodes(idx).Text + " (default)";
            end

            default = "ThreeFry";
            nodes = app.GPUGeneratorTree.Children.Children;
            idx = find(strcmpi({nodes.Text}, default));
            if isscalar(idx)
                nodes(idx).Text = nodes(idx).Text + " (default)";
            end

            app.plotResults()
        end

        % Callback function: CPUGeneratorTree, GPUGeneratorTree
        function GeneratorTreeCheckedNodesChanged(app, event)
            % A generator has been added or removed from the list.
            app.plotResults()
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            % Put the plotted data into a table and assign it into the base
            % workspace.
            tbl = table(app.PlottedNames', app.PlottedValues', ...
                VariableNames = ["Generator", "Samples per Second"]);
            assignin("base","rngSpeed",tbl)
        end

        % Value changed function: FunctionDropDown
        function FunctionDropDownValueChanged(app, event)
            app.RandFunction = string(app.FunctionDropDown.Value);
            app.invalidateResults();
            app.plotResults();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Comparing RNGs')
            xlabel(app.UIAxes, 'Generator')
            ylabel(app.UIAxes, 'Samples / sec')
            zlabel(app.UIAxes, 'Z')
            app.CPUGeneratorTree = uitree(app.UIFigure, 'checkbox');
            app.CPUGeneratorTree.Tooltip = {'Select which random number generators to include in the comparison. Click "CPU Generators" to select or deselect all.'};
            app.CPUGeneratorsNode = uitreenode(app.CPUGeneratorTree);
            app.CPUGeneratorsNode.Text = 'CPU Generators';
            app.TwisterNode = uitreenode(app.CPUGeneratorsNode);
            app.TwisterNode.Text = 'Twister';
            app.SIMDTwisterNode = uitreenode(app.CPUGeneratorsNode);
            app.SIMDTwisterNode.Text = 'SIMDTwister';
            app.CombRecursiveNode = uitreenode(app.CPUGeneratorsNode);
            app.CombRecursiveNode.Text = 'CombRecursive';
            app.MultFibonacciNode = uitreenode(app.CPUGeneratorsNode);
            app.MultFibonacciNode.Text = 'MultFibonacci';
            app.PhiloxNode = uitreenode(app.CPUGeneratorsNode);
            app.PhiloxNode.Text = 'Philox';
            app.ThreefryNode = uitreenode(app.CPUGeneratorsNode);
            app.ThreefryNode.Text = 'Threefry';
            % Assign Checked Nodes
            app.CPUGeneratorTree.CheckedNodesChangedFcn = createCallbackFcn(app, @GeneratorTreeCheckedNodesChanged, true);
            app.GPUGeneratorTree = uitree(app.UIFigure, 'checkbox');
            app.GPUGeneratorTree.Tooltip = {'Select which GPU random number generators to include in the comparison. Click "GPU Generators" to select or deselect all.'};
            app.GPUGeneratorsNode = uitreenode(app.GPUGeneratorTree);
            app.GPUGeneratorsNode.Text = 'GPU Generators';
            app.CombRecursiveNode_2 = uitreenode(app.GPUGeneratorsNode);
            app.CombRecursiveNode_2.Text = 'CombRecursive';
            app.PhiloxNode_2 = uitreenode(app.GPUGeneratorsNode);
            app.PhiloxNode_2.Text = 'Philox';
            app.ThreefryNode_2 = uitreenode(app.GPUGeneratorsNode);
            app.ThreefryNode_2.Text = 'Threefry';
            % Assign Checked Nodes
            app.GPUGeneratorTree.CheckedNodesChangedFcn = createCallbackFcn(app, @GeneratorTreeCheckedNodesChanged, true);
            app.ExporttoWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Tooltip = {'Export the plotted data as a table in the MATLAB workspace (called rngSpeed).'};
            app.ExporttoWorkspaceButton.Text = 'Export to Workspace';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function:';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'rand', 'randn'};
            app.FunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @FunctionDropDownValueChanged, true);
            app.FunctionDropDown.Value = 'rand';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CompareRNG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end